classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CalculatedResultsPanel     matlab.ui.container.Panel
        ZinLabel                   matlab.ui.control.Label
        GammaLabel                 matlab.ui.control.Label
        SWRLabel                   matlab.ui.control.Label
        InputParametersPanel       matlab.ui.container.Panel
        LengthEditField            matlab.ui.control.EditField
        LineLengthlEditFieldLabel  matlab.ui.control.Label
        CalculateButton            matlab.ui.control.Button
        ZLEditField                matlab.ui.control.EditField
        ZLOhmsEditFieldLabel       matlab.ui.control.Label
        Z0EditField                matlab.ui.control.NumericEditField
        Z0OhmsEditFieldLabel       matlab.ui.control.Label
        SmithAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % --- 1. Get Values from GUI ---
    Z0 = app.Z0EditField.Value;
    ZL_str = app.ZLEditField.Value;
    l_str = app.LengthEditField.Value; % Get length as a string

    % Use str2num to handle complex inputs
    try
        ZL = str2num(ZL_str);
        l = str2num(l_str); % Convert length string to a number
        
        if isempty(ZL) || isempty(l)
            error('Invalid ZL or Length format'); % Trigger the catch block
        end
    catch
        app.SWRLabel.Text = 'Error: Invalid ZL or Length.';
        return; % Stop the function
    end

    % --- 2. Core EM Calculations ---
    
    % Calculate Reflection Coefficient (Gamma)
    Gamma = (ZL - Z0) / (ZL + Z0);
    
    % Calculate SWR
    SWR = (1 + abs(Gamma)) / (1 - abs(Gamma));
    
    % We'll treat 'l' as the electrical length (beta * l) in radians
    beta_l = l; 
    
    % Calculate Input Impedance (Zin)
    numerator = ZL + 1i*Z0*tan(beta_l);
    denominator = Z0 + 1i*ZL*tan(beta_l);
    Zin = Z0 * (numerator / denominator);
    
    % Calculate Gamma at the input
    Gamma_in = (Zin - Z0) / (Zin + Z0);

    % --- 3. Update ALL GUI Labels ---
    
    % Update SWR
    app.SWRLabel.Text = ['SWR: ', num2str(SWR, '%.3f')];
    
    % Update Zin Label (format as "real + j(imag)")
    app.ZinLabel.Text = ['Z_in (Ohms): ', num2str(real(Zin), '%.2f'), ...
                         ' + j(', num2str(imag(Zin), '%.2f'), ')'];
    
    % Update Gamma Label (format as "magnitude < angle")
    gamma_mag = abs(Gamma);
    gamma_angle = angle(Gamma) * 180/pi; % Convert radians to degrees
    app.GammaLabel.Text = ['Gamma: ', num2str(gamma_mag, '%.3f'), ...
                           ' < ', num2str(gamma_angle, '%.1f'), ' deg'];
    
    drawnow; % Force GUI to update all labels

    % --- 4. Plot on the Smith Chart Axes ---
    ax = app.SmithAxes;
    cla(ax); % Clear the axes
    hold(ax, 'on'); % Keep drawing on the same axes

    % Draw the main |Gamma| = 1 circle
    theta_circ = linspace(0, 2*pi, 100);
    plot(ax, cos(theta_circ), sin(theta_circ), ...
         'Color', [0.7 0.7 0.7], 'LineStyle', ':');
    
    % Draw the SWR circle (radius = |Gamma|)
    r_swr = abs(Gamma);
    plot(ax, r_swr*cos(theta_circ), r_swr*sin(theta_circ), ...
         'Color', 'r', 'LineStyle', '--');

    % Draw the x and y axes
    line(ax, [-1 1], [0 0], 'Color', [0.5 0.5 0.5], 'LineStyle', '--');
    line(ax, [0 0], [-1 1], 'Color', [0.5 0.5 0.5], 'LineStyle', '--');
    
    % Plot the Load Gamma point (Red Star)
    plot(ax, real(Gamma), imag(Gamma), 'r*', ...
         'MarkerSize', 12, 'LineWidth', 2);
         
    % Plot the Input Gamma point (Blue Circle)
    plot(ax, real(Gamma_in), imag(Gamma_in), 'bo', ...
         'MarkerSize', 10, 'LineWidth', 2);

    % Make the plot look good
    axis(ax, 'equal'); % Makes the circle look like a circle
    axis(ax, [-1.1 1.1 -1.1 1.1]);
    title(ax, 'Complex Reflection Coefficient (\Gamma) Plane', 'Color', 'w');
    xlabel(ax, 'Real(\Gamma)', 'Color', 'w');
    ylabel(ax, 'Imag(\Gamma)', 'Color', 'w');
    ax.Color = 'k'; ax.XColor = 'w'; ax.YColor = 'w';
    grid(ax, 'on');
    
    % Add a Legend
    legend(ax, {'Unit Circle', 'SWR Circle', 'Load (\Gamma_L)', 'Input (\Gamma_{in})'}, ...
           'Color', [0.3 0.3 0.3], 'TextColor', 'w', 'Location', 'northwest');
           
    hold(ax, 'off'); % We are done drawing
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SmithAxes = uiaxes(app.UIFigure);
            title(app.SmithAxes, 'SmithAxes')
            xlabel(app.SmithAxes, 'X')
            ylabel(app.SmithAxes, 'Y')
            zlabel(app.SmithAxes, 'Z')
            app.InputParametersPanel = uipanel(app.UIFigure);
            app.InputParametersPanel.Title = 'Input Parameters';
            app.Z0OhmsEditFieldLabel = uilabel(app.InputParametersPanel);
            app.Z0OhmsEditFieldLabel.Text = {'Z0 (Ohms)'; ''};
            app.Z0EditField = uieditfield(app.InputParametersPanel, 'numeric');
            app.ZLOhmsEditFieldLabel = uilabel(app.InputParametersPanel);
            app.ZLOhmsEditFieldLabel.Text = 'ZL (Ohms)';
            app.ZLEditField = uieditfield(app.InputParametersPanel, 'text');
            app.CalculateButton = uibutton(app.InputParametersPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.LineLengthlEditFieldLabel = uilabel(app.InputParametersPanel);
            app.LineLengthlEditFieldLabel.Text = 'Line Length (l)';
            app.LengthEditField = uieditfield(app.InputParametersPanel, 'text');
            app.CalculatedResultsPanel = uipanel(app.UIFigure);
            app.CalculatedResultsPanel.Title = 'Calculated Results';
            app.SWRLabel = uilabel(app.CalculatedResultsPanel);
            app.SWRLabel.Text = 'SWR: --';
            app.GammaLabel = uilabel(app.CalculatedResultsPanel);
            app.GammaLabel.Text = 'Gamma: --';
            app.ZinLabel = uilabel(app.CalculatedResultsPanel);
            app.ZinLabel.Text = 'Z_in (Ohms): --';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end